classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        TextArea2                    matlab.ui.control.TextArea
        OutputPanel                  matlab.ui.container.Panel
        GridLayout6                  matlab.ui.container.GridLayout
        TextArea                     matlab.ui.control.TextArea
        Image                        matlab.ui.control.Image
        RightPanel                   matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        UserInputsPanel              matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        EnterWaistCircumferenceEditField  matlab.ui.control.NumericEditField
        EnterWaistCircumferenceEditFieldLabel  matlab.ui.control.Label
        Range6500yrage1000yrLabel_2  matlab.ui.control.Label
        Range6500yrage1000yrLabel    matlab.ui.control.Label
        Range550mweight750kgLabel    matlab.ui.control.Label
        EnterAgeEditField            matlab.ui.control.NumericEditField
        EnterAgeEditFieldLabel       matlab.ui.control.Label
        EnterWeightEditField         matlab.ui.control.NumericEditField
        EnterWeightEditFieldLabel    matlab.ui.control.Label
        Range100mheight200mLabel     matlab.ui.control.Label
        EnterHeightEditField         matlab.ui.control.NumericEditField
        EnterHeightEditFieldLabel    matlab.ui.control.Label
        GridLayout3                  matlab.ui.container.GridLayout
        GenerateDesignButton         matlab.ui.control.Button
        ExitButton                   matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        user_data; % struct containing user_data
        output; % output log file data
        output_log_path = "output.txt" % path to output log file
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('code'));
            app.user_data = struct( ...
                        'height', 0, ...
                        'weight', 0, ...
                        'age', 0, ...
                        'waist_circumference', 0);
            fileID = fopen(app.output_log_path, 'w');
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: EnterHeightEditField
        function EnterHeightEditFieldValueChanged(app, event)
            value = app.EnterHeightEditField.Value;
            app.user_data.height = value;
        end

        % Value changed function: EnterWeightEditField
        function EnterWeightEditFieldValueChanged(app, event)
            value = app.EnterWeightEditField.Value;
            app.user_data.weight = value;
        end

        % Value changed function: EnterAgeEditField
        function EnterAgeEditFieldValueChanged(app, event)
            value = app.EnterAgeEditField.Value;
            app.user_data.age = value;
        end

        % Button pushed function: GenerateDesignButton
        function GenerateDesignButtonPushed(app, event)
            if (~app.user_data.height || ~app.user_data.weight || ~app.user_data.age || ~app.user_data.waist_circumference)
                message = "Make sure to set all four inputs.";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon", "warning");
            else
                app.output = fileread(app.output_log_path);
                app.TextArea.Value = app.output;
                system_design(app.user_data);
            end
        end

        % Callback function
        function EnterWaistDiaEditFieldValueChanged(app, event)
            value = app.EnterWaistDiaEditField.Value;
            app.user_data.waist_circumference = value;
        end

        % Value changed function: EnterWaistCircumferenceEditField
        function EnterWaistCircumferenceEditFieldValueChanged(app, event)
            value = app.EnterWaistCircumferenceEditField.Value;
            app.user_data.waist_circumference = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {310, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.LeftPanel);
            app.Image = uiimage(app.GridLayout5);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'img_tmp.jpg');
            app.OutputPanel = uipanel(app.GridLayout5);
            app.OutputPanel.Title = 'Output';
            app.GridLayout6 = uigridlayout(app.OutputPanel);
            app.TextArea = uitextarea(app.GridLayout6);
            app.TextArea2 = uitextarea(app.GridLayout5);
            app.TextArea2.Editable = 'off';
            app.TextArea2.Value = {' _____ _    ______ _____ _____  ___  _   _ ___________ '; '|  ___| |   |  _  \  _  /  ___|/ _ \| | | |  ___| ___ \'; '| |__ | |   | | | | | | \ `--./ /_\ \ | | | |__ | |_/ /'; '|  __|| |   | | | | | | |`--. \  _  | | | |  __||    / '; '| |___| |___| |/ /\ \_/ /\__/ / | | \ \_/ / |___| |\ \ '; '\____/\_____/___/  \___/\____/\_| |_/\___/\____/\_| \_|'};
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.ExitButton = uibutton(app.GridLayout3, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.GenerateDesignButton = uibutton(app.GridLayout3, 'push');
            app.GenerateDesignButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateDesignButtonPushed, true);
            app.GenerateDesignButton.Text = 'Generate Design';
            app.UserInputsPanel = uipanel(app.GridLayout2);
            app.UserInputsPanel.Title = 'User Inputs:';
            app.GridLayout4 = uigridlayout(app.UserInputsPanel);
            app.EnterHeightEditFieldLabel = uilabel(app.GridLayout4);
            app.EnterHeightEditFieldLabel.Text = 'Enter Height:';
            app.EnterHeightEditField = uieditfield(app.GridLayout4, 'numeric');
            app.EnterHeightEditField.Limits = [1 2];
            app.EnterHeightEditField.ValueDisplayFormat = '%.2f';
            app.EnterHeightEditField.AllowEmpty = 'on';
            app.EnterHeightEditField.ValueChangedFcn = createCallbackFcn(app, @EnterHeightEditFieldValueChanged, true);
            app.EnterHeightEditField.Placeholder = 'Enter height in meters!';
            app.EnterHeightEditField.Value = [];
            app.Range100mheight200mLabel = uilabel(app.GridLayout4);
            app.Range100mheight200mLabel.Text = 'Range --> [1.00m <= height <= 2.00m]';
            app.EnterWeightEditFieldLabel = uilabel(app.GridLayout4);
            app.EnterWeightEditFieldLabel.Text = 'Enter Weight:';
            app.EnterWeightEditField = uieditfield(app.GridLayout4, 'numeric');
            app.EnterWeightEditField.Limits = [60.5 119.6];
            app.EnterWeightEditField.ValueDisplayFormat = '%.1f';
            app.EnterWeightEditField.AllowEmpty = 'on';
            app.EnterWeightEditField.ValueChangedFcn = createCallbackFcn(app, @EnterWeightEditFieldValueChanged, true);
            app.EnterWeightEditField.Placeholder = 'Enter weight in kg!';
            app.EnterWeightEditField.Value = [];
            app.EnterAgeEditFieldLabel = uilabel(app.GridLayout4);
            app.EnterAgeEditFieldLabel.Text = 'Enter Age:';
            app.EnterAgeEditField = uieditfield(app.GridLayout4, 'numeric');
            app.EnterAgeEditField.Limits = [65 100];
            app.EnterAgeEditField.ValueDisplayFormat = '%.0f';
            app.EnterAgeEditField.AllowEmpty = 'on';
            app.EnterAgeEditField.ValueChangedFcn = createCallbackFcn(app, @EnterAgeEditFieldValueChanged, true);
            app.EnterAgeEditField.Placeholder = 'Enter age in years!';
            app.EnterAgeEditField.Value = [];
            app.Range550mweight750kgLabel = uilabel(app.GridLayout4);
            app.Range550mweight750kgLabel.Text = 'Range --> [55.0m <= weight <= 75.0kg]';
            app.Range6500yrage1000yrLabel = uilabel(app.GridLayout4);
            app.Range6500yrage1000yrLabel.Text = 'Range --> [65.00yr <= age <= 100.0yr]';
            app.Range6500yrage1000yrLabel_2 = uilabel(app.GridLayout4);
            app.Range6500yrage1000yrLabel_2.Text = 'Range --> [65.00yr <= age <= 100.0yr]';
            app.EnterWaistCircumferenceEditFieldLabel = uilabel(app.GridLayout4);
            app.EnterWaistCircumferenceEditFieldLabel.Text = 'Enter Waist Circumference:';
            app.EnterWaistCircumferenceEditField = uieditfield(app.GridLayout4, 'numeric');
            app.EnterWaistCircumferenceEditField.Limits = [857 1331.3];
            app.EnterWaistCircumferenceEditField.ValueDisplayFormat = '%.1f';
            app.EnterWaistCircumferenceEditField.AllowEmpty = 'on';
            app.EnterWaistCircumferenceEditField.ValueChangedFcn = createCallbackFcn(app, @EnterWaistCircumferenceEditFieldValueChanged, true);
            app.EnterWaistCircumferenceEditField.Placeholder = 'Enter waist circumference!';
            app.EnterWaistCircumferenceEditField.Value = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end